classdef myapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Button          matlab.ui.control.Button
        TabGroup        matlab.ui.container.TabGroup
        Tab_2           matlab.ui.container.Tab
        Original_image  matlab.ui.control.Image
        Tab_3           matlab.ui.container.Tab
        text1           matlab.ui.control.Image
        Tab             matlab.ui.container.Tab
        TextArea        matlab.ui.control.TextArea
        Image           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Загрузить цветное изображение из файла "foto_1.jpg"
            colorImage = imread("foto_1.jpg");
            
            % Вывести оригинальное изображение в элемент управления Original_image
            app.Original_image.ImageSource = colorImage;
            
            % Проверить, что изображение имеет три канала (RGB)
            assert(size(colorImage, 3) == 3, 'Изображение должно быть цветным (RGB).');
            
            % Преобразовать изображение в оттенки серого
            I = rgb2gray(colorImage);
            
            % Обнаружить регионы максимально стабильных экстремальных областей (MSER)
            [mserRegions, mserConnComp] = detectMSERFeatures(I, ...
                'RegionAreaRange',[200 8000],'ThresholdDelta',4);
            
            % Извлечь статистику регионов MSER
            mserStats = regionprops(mserConnComp, 'BoundingBox', 'Eccentricity', ...
                'Solidity', 'Extent', 'Euler', 'Image');
            
            % Фильтровать регионы MSER по геометрическим свойствамФ
            bbox = vertcat(mserStats.BoundingBox);
            w = bbox(:,3);
            h = bbox(:,4);
            aspectRatio = w./h;
            
            filterIdx = aspectRatio' > 3;
            filterIdx = filterIdx | [mserStats.Eccentricity] > .995 ;
            filterIdx = filterIdx | [mserStats.Solidity] < .3;
            filterIdx = filterIdx | [mserStats.Extent] < 0.2 | [mserStats.Extent] > 0.9;
            filterIdx = filterIdx | [mserStats.EulerNumber] < -4;
            
            mserStats(filterIdx) = [];
            mserRegions(filterIdx) = [];
            
            % Подготовить изображение региона для анализа ширины штриха
            regionImage = mserStats(6).Image;
            regionImage = padarray(regionImage, [1 1]);
            
            % Создать изображение расстояний и скелет
            distanceImage = bwdist(~regionImage);
            skeletonImage = bwmorph(regionImage, 'thin', inf);
            
            % Создать изображение ширины штриха
            strokeWidthImage = distanceImage;
            strokeWidthImage(~skeletonImage) = 0;
            
            % Оценить ширину штриха и отфильтровать регионы MSER
            strokeWidthValues = distanceImage(skeletonImage);
            strokeWidthMetric = std(strokeWidthValues)/mean(strokeWidthValues);
            strokeWidthThreshold = 0.4;
            strokeWidthFilterIdx = strokeWidthMetric > strokeWidthThreshold;
            for j = 1:numel(mserStats)
                regionImage = mserStats(j).Image;
                regionImage = padarray(regionImage, [1 1], 0);
                distanceImage = bwdist(~regionImage);
                skeletonImage = bwmorph(regionImage, 'thin', inf);
                strokeWidthValues = distanceImage(skeletonImage);
                strokeWidthMetric = std(strokeWidthValues)/mean(strokeWidthValues);
                strokeWidthFilterIdx(j) = strokeWidthMetric > strokeWidthThreshold;
            end
            
            mserRegions(strokeWidthFilterIdx) = [];
            mserStats(strokeWidthFilterIdx) = [];
            
            % Получить ограничивающие рамки текста
            bboxes = vertcat(mserStats.BoundingBox);
            
            xmin = bboxes(:,1);
            ymin = bboxes(:,2);
            xmax = xmin + bboxes(:,3) - 1;
            ymax = ymin + bboxes(:,4) - 1;
            
            % Расширить ограничивающие рамки текста
            expansionAmount = 0.02;
            xmin = (1-expansionAmount) * xmin;
            ymin = (1-expansionAmount) * ymin;
            xmax = (1+expansionAmount) * xmax;
            ymax = (1+expansionAmount) * ymax;
            
            % Обрезать значения, чтобы они оставались в пределах изображения
            xmin = max(xmin, 1);
            ymin = max(ymin, 1);
            xmax = min(xmax, size(I,2));
            ymax = min(ymax, size(I,1));
            
            % Обновленные ограничивающие рамки
            expandedBBoxes = [xmin ymin xmax-xmin+1 ymax-ymin+1];
            
            % Оценить перекрытие ограничивающих рамок
            overlapRatio = bboxOverlapRatio(expandedBBoxes, expandedBBoxes);
            
            % Исключить диагональные элементы
            n = size(overlapRatio,1);
            overlapRatio(1:n+1:n^2) = 0;
            
            % Создать граф перекрытия
            g = graph(overlapRatio);
            
            % Определить компоненты связности в графе
            componentIndices = conncomp(g);
            xmin = accumarray(componentIndices', xmin, [], @min);
            ymin = accumarray(componentIndices', ymin, [], @min);
            xmax = accumarray(componentIndices', xmax, [], @max);
            ymax = accumarray(componentIndices', ymax, [], @max);
            
            % Обновить ограничивающие рамки текста, объединив перекрывающиеся рамки
            textBBoxes = [xmin ymin xmax-xmin+1 ymax-ymin+1];
            numRegionsInGroup = histcounts(componentIndices);
            textBBoxes(numRegionsInGroup == 1, :) = [];
            
            % Вывести изображение с обнаруженным текстом и ограничивающими рамками
            ITextRegion = insertShape(colorImage, 'Rectangle', textBBoxes,'LineWidth',3);
            app.text1.ImageSource = ITextRegion;
            
            % Применить оптическое распознавание символов (OCR) к изображению
            ocr_results = ocr(colorImage);
            
            % Извлечь распознанный текст
            recognized_text = ocr_results.Text;
            
            % Вывести распознанный текст в TextArea
            app.TextArea.Value = recognized_text;
        end

        % Image clicked function: Original_image
        function Original_imageImageClicked(app, event)
            app.Original_image.ImageSource = "404.png";
        end

        % Image clicked function: text1
        function text1ImageClicked(app, event)
            app.text1.ImageSource = "404.png";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'fon.jpg');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'исходная фотография';
            app.Original_image = uiimage(app.Tab_2);
            app.Original_image.ImageClickedFcn = createCallbackFcn(app, @Original_imageImageClicked, true);
            app.Original_image.ImageSource = fullfile(pathToMLAPP, 'button.png');
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'выделенная рамками';
            app.text1 = uiimage(app.Tab_3);
            app.text1.ImageClickedFcn = createCallbackFcn(app, @text1ImageClicked, true);
            app.text1.ImageSource = fullfile(pathToMLAPP, 'button.png');
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'текст с картинки';
            app.TextArea = uitextarea(app.Tab);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'кнопка делать хорошо';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end